classdef EyeTracknig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StartButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        StartArduinoconnectionButton  matlab.ui.control.Button
        MessagesEditFieldLabel        matlab.ui.control.Label
        MessagesEditField             matlab.ui.control.EditField
        Image                         matlab.ui.control.Image
        RightsensorEditFieldLabel     matlab.ui.control.Label
        RightsensorEditField          matlab.ui.control.NumericEditField
        LeftsensorEditFieldLabel      matlab.ui.control.Label
        LeftsensorEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        conn = 0;
        blink = 0;
        a = 0;
        blinkIm;
        right;
        left;
        center;
    end
    
    methods (Access = private)
        
        function analogRead(app)
            while app.a
                app.blink = randi([0 1]);
                if app.blink
                    app.RightsensorEditField.Value = randi(203);
                    app.LeftsensorEditField.Value = randi(203);
                    app.MessagesEditField.Value = 'Reading active / Blinking';
                    app.Image.ImageSource = app.blinkIm;
                    pause(2);
                else
                    app.RightsensorEditField.Value = randi(1023);
                    app.LeftsensorEditField.Value = 1023 - app.RightsensorEditField.Value;
                    if app.RightsensorEditField.Value > 600
                        app.MessagesEditField.Value = 'Reading active / Looking to the right';
                        app.Image.ImageSource = app.right;
                    elseif app.RightsensorEditField.Value < 423
                        app.MessagesEditField.Value = 'Reading active / Looking to the left';
                        app.Image.ImageSource = app.left;
                    pause(2);
                    end
                end
                app.RightsensorEditField.Value = randi([423 600]);
                app.LeftsensorEditField.Value = 1023 - app.RightsensorEditField.Value;
                app.MessagesEditField.Value = 'Reading active / Looking to the center';
                app.Image.ImageSource = app.center;
                pause(2);                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartArduinoconnectionButton
        function StartArduinoconnectionButtonPushed(app, event)
            if app.conn == 0
                app.conn = 1;
                app.MessagesEditField.Value = 'Connection established';
            else
                app.MessagesEditField.Value = 'Connection already established';
            end
            app.blinkIm = imread('blink.jpg');
            app.right = imread('right.jpg');
            app.left = imread('left.jpg');
            app.center = imread('center.jpg');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.a = 1;
            app.MessagesEditField.Value = 'Reading active';
            app.analogRead();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.a = 0;
            app.MessagesEditField.Value = 'Reading stopped';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartArduinoconnectionButton = uibutton(app.UIFigure, 'push');
            app.StartArduinoconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartArduinoconnectionButtonPushed, true);
            app.StartArduinoconnectionButton.Text = 'Start Arduino connection';
            app.MessagesEditFieldLabel = uilabel(app.UIFigure);
            app.MessagesEditFieldLabel.Text = 'Messages';
            app.MessagesEditField = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.RightsensorEditFieldLabel = uilabel(app.UIFigure);
            app.RightsensorEditFieldLabel.Text = 'Right sensor';
            app.RightsensorEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftsensorEditFieldLabel = uilabel(app.UIFigure);
            app.LeftsensorEditFieldLabel.Text = 'Left sensor';
            app.LeftsensorEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EyeTracknig

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end