classdef arduinoTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        ArduinopinvalueEditFieldLabel  matlab.ui.control.Label
        PinEdit                        matlab.ui.control.NumericEditField
        StartArduinoconnectionButton   matlab.ui.control.Button
        MessagesEditFieldLabel         matlab.ui.control.Label
        MessagesEditField              matlab.ui.control.EditField
        LampLabel                      matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        conn = 0;
        a = 0;
        ard;
    end
    
    methods (Access = private)
        
        function digitalRead(app, ard)
            while app.a
                pin = readDigitalPin(ard, 'D2');
                app.PinEdit.Value = pin;
                switch pin
                    case 1
                        app.Lamp.Color = [1 0 0];
                    case 0
                        app.Lamp.Color = [0 1 0];
                end
                pause(1);                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartArduinoconnectionButton
        function StartArduinoconnectionButtonPushed(app, event)
            if app.conn == 0
                app.ard = arduino('com3', 'uno');
                app.conn = 1;
                app.MessagesEditField.Value = 'Connection established';
            else
                app.MessagesEditField.Value = 'Connection already established';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.a = 1;
            app.MessagesEditField.Value = 'Reading active';
            app.digitalRead(app.ard);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.a = 0;
            app.MessagesEditField.Value = 'Reading stopped';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ArduinopinvalueEditFieldLabel = uilabel(app.UIFigure);
            app.ArduinopinvalueEditFieldLabel.Text = 'Arduino pin value:';
            app.PinEdit = uieditfield(app.UIFigure, 'numeric');
            app.StartArduinoconnectionButton = uibutton(app.UIFigure, 'push');
            app.StartArduinoconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartArduinoconnectionButtonPushed, true);
            app.StartArduinoconnectionButton.Text = 'Start Arduino connection';
            app.MessagesEditFieldLabel = uilabel(app.UIFigure);
            app.MessagesEditFieldLabel.Text = 'Messages';
            app.MessagesEditField = uieditfield(app.UIFigure, 'text');
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arduinoTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end